classdef droneV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        openLampLabel       matlab.ui.control.Label
        openLamp            matlab.ui.control.Lamp
        PortEditFieldLabel  matlab.ui.control.Label
        PortEditField       matlab.ui.control.NumericEditField
        IPEditFieldLabel    matlab.ui.control.Label
        IPEditField         matlab.ui.control.EditField
        connectButton       matlab.ui.control.Button
        closeButton         matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function readData(app,~,~)%利用定时器固定实际接收数据
      
        global SocketData;


           b = SocketPointCloudDataRecv(SocketData);           
           x = b(1,:);
           y = b(2,:);
           z = b(3,:);
          value = b(4,:);
          figure(1);
        xlabel('x');
        ylabel('z');
        zlabel('y');
        title('Unity 获取的点云图显示')
       
          plot3(x,z,y,'*');           
           [X,Y,Z] = griddata(x,y,z,linspace(min(x),max(x)),linspace(min(y),max(y)),'V4');


       % disp('read');


        end
        
        function sendData(app,~,~)%利用定时器固定实际发送数据
            
       % disp('send');
        
        end
        
        
        function TimerInit(app)%定时器初始化函数
            global readTimer;
            global sendTimer;%全局变量
            global cnetTimer;
              readTimer = timer;%定时器类%读取数据
              readTimer.TimerFcn = @app.readData;%指定定时调用函数
              readTimer.ExecutionMode = 'fixedRate';%执行的模式是固定时间调用
              readTimer.Period = 0.7;%调用间隔时间1s
              
              sendTimer = timer;%发送数据
              sendTimer.TimerFcn = @app.sendData;
              sendTimer.ExecutionMode = 'fixedRate';
              sendTimer.Period = 1;
              
              cnetTimer = timer;%自动断线重连
              cnetTimer.TimerFcn = @app.autoReConnection;
              cnetTimer.ExecutionMode = 'fixedRate';
              cnetTimer.Period = 5;
        
            
        end
        
        function autoReConnection(app,~,~)%断线重连定时器调用函数
            disp('auto');
        end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
            
            TimerInit(app);
            
            global readTimer;
            global sendTimer;
            global cnetTimer;
            global SocketData;
           SocketData = StartSocket(8080); 
            app.openLamp.Color = [0,1,0];
            start(readTimer);
            start(sendTimer);
            start(cnetTimer);
        %set(app.dataLabel,'Text',rawData);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
           
            
        end

        % Button pushed function: closeButton
        function closeButtonPushed(app, event)
         
            global readTimer;
            global sendTimer;
            global cnetTimer;
            global SocketData;            
           
            stop(readTimer);
            stop(sendTimer);
            stop(cnetTimer);
            
            
            delete(readTimer);
            delete(sendTimer);
            delete(cnetTimer);
            CloseSocket(SocketData);
            
            app.openLamp.Color = [1,1,1];
           
        end

        % Callback function
        function ButtonPushed(app, event)
            disp(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.openLampLabel = uilabel(app.UIFigure);
            app.openLampLabel.Text = 'open';
            app.openLamp = uilamp(app.UIFigure);
            app.PortEditFieldLabel = uilabel(app.UIFigure);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.UIFigure, 'numeric');
            app.PortEditField.Value = 8001;
            app.IPEditFieldLabel = uilabel(app.UIFigure);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.UIFigure, 'text');
            app.IPEditField.Value = '192.168.0.189';
            app.connectButton = uibutton(app.UIFigure, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = 'connect';
            app.closeButton = uibutton(app.UIFigure, 'push');
            app.closeButton.ButtonPushedFcn = createCallbackFcn(app, @closeButtonPushed, true);
            app.closeButton.Text = 'close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = droneV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end